classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ATTILAUIFigure                  matlab.ui.Figure
        AppGridLayout                   matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        OutputTab                       matlab.ui.container.Tab
        OutputGridLayout                matlab.ui.container.GridLayout
        SumField                        matlab.ui.control.EditField
        DisplayButton                   matlab.ui.control.Button
        ComputeButton                   matlab.ui.control.Button
        VoltagesList                    matlab.ui.control.ListBox
        CurrentsList                    matlab.ui.control.ListBox
        SignalsPlot                     matlab.ui.control.UIAxes
        ModelTab                        matlab.ui.container.Tab
        ModelGridLayout                 matlab.ui.container.GridLayout
        GraphPlot                       matlab.ui.control.UIAxes
        VoltageGraphPlot                matlab.ui.control.UIAxes
        CurrentGraphPlot                matlab.ui.control.UIAxes
        GeneralTab                      matlab.ui.container.Tab
        GeneralGridLayout               matlab.ui.container.GridLayout
        SamplingFrequencyField          matlab.ui.control.NumericEditField
        SamplingFrequencyLabel          matlab.ui.control.Label
        ElapsedTimeValue                matlab.ui.control.Label
        ElapsedTimeLabel                matlab.ui.control.Label
        OrderField                      matlab.ui.control.NumericEditField
        OrderLabel                      matlab.ui.control.Label
        SimulationlengthsLabel          matlab.ui.control.Label
        BrowseInputLabel                matlab.ui.control.Label
        SimulationTimeField             matlab.ui.control.NumericEditField
        BrowseInputButton               matlab.ui.control.Button
        NetlistFileLabel                matlab.ui.control.Label
        DSRToleranceField               matlab.ui.control.NumericEditField
        DSRtoleranceEditFieldLabel      matlab.ui.control.Label
        SolverToleranceField            matlab.ui.control.NumericEditField
        SolvertoleranceVEditFieldLabel  matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        FunctionField                   matlab.ui.control.EditField
        SolverSettingsLabel             matlab.ui.control.Label
        SimulationOptionsLabel          matlab.ui.control.Label
        CircuitNetlistLabel             matlab.ui.control.Label
        InputSignalLabel                matlab.ui.control.Label
        SPICENetlistLabel               matlab.ui.control.Label
        BrowseNetlistButton             matlab.ui.control.Button
        DSRAlgorithmLabel               matlab.ui.control.Label
        DSRAlgorithmCheckBox            matlab.ui.control.CheckBox
        InputTypeDropDown               matlab.ui.control.DropDown
        InputtypeDropDownLabel          matlab.ui.control.Label
        IterativeSolverDropDown         matlab.ui.control.DropDown
        IterativesolverDropDownLabel    matlab.ui.control.Label
        DiscretizationMethodDropDown    matlab.ui.control.DropDown
        DiscretizationmethodDropDownLabel  matlab.ui.control.Label
        WaveTypeDropDown                matlab.ui.control.DropDown
        WavetypeDropDownLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        progressBar;
        errorWindow;
        inputPath = '';
        simTime = 0.01;
        netlistPath = '';
        waveType = 'Voltage';
        lmsdm = 'Trapezoidal';
        tolDSR = 1e3;
        tolSLV = 1e-3;
        iterSol = 'SIM';
        toLEFP = 1;
        x_in = 0;
        f_s = 48000;
        time;
        eTime;
        y_out;
        v_out;
        i_out;
        order;
        selectedVoltages = {};
        selectedCurrents = {};
        signals;
        typeFlag = 'Volts';
    end
    
    methods (Access = private)
        
        function enableFunctionInput(app)
            app.FunctionField.Visible = 'on';
            app.FunctionField.Editable = 'on';
            app.FunctionField.Enable = 'on';
        end

        function disableFunctionInput(app)
            app.FunctionField.Visible = 'off';
            app.FunctionField.Editable = 'off';
            app.FunctionField.Enable = 'off';
        end

        function enableBrowseInput(app)
            app.BrowseInputButton.Visible = 'on';
            app.BrowseInputButton.Enable = 'on';
            app.BrowseInputLabel.Visible = 'on';
            app.BrowseInputLabel.Enable = 'on';
        end

        function disableBrowseInput(app)
            app.BrowseInputButton.Visible = 'off';
            app.BrowseInputButton.Enable = 'off';
            app.BrowseInputLabel.Visible = 'off';
            app.BrowseInputLabel.Enable = 'off';
        end

        function enableOrderInput(app)
            app.OrderLabel.Visible = 'on';
            app.OrderLabel.Enable = 'on';
            app.OrderField.Visible = 'on';
            app.OrderField.Enable = 'on';
            app.OrderField.Editable = 'on';
        end

        function disableOrderInput(app)
            app.OrderLabel.Visible = 'off';
            app.OrderLabel.Enable = 'off';
            app.OrderField.Visible = 'off';
            app.OrderField.Enable = 'off';
            app.OrderField.Editable = 'off';
        end

        function enableElapsedTimeField(app, value)
            app.ElapsedTimeValue.Visible = 'on';
            app.ElapsedTimeValue.Enable = 'on';
            app.ElapsedTimeLabel.Visible = 'on';
            app.ElapsedTimeLabel.Enable = 'on';
            app.ElapsedTimeValue.Text = value;
        end

        function disableElapsedTimeField(app)
            app.ElapsedTimeValue.Visible = 'off';
            app.ElapsedTimeValue.Enable = 'off';
            app.ElapsedTimeLabel.Visible = 'off';
            app.ElapsedTimeLabel.Enable = 'off';
        end

        function disableSamplingFrequencyField(app)
            app.SamplingFrequencyField.Visible = 'off';
            app.SamplingFrequencyField.Enable = 'off';
            app.SamplingFrequencyField.Editable = 'off';
            app.SamplingFrequencyLabel.Visible = 'off';
            app.SamplingFrequencyLabel.Enable = 'off';
        end

        function enableSamplingFrequencyField(app)
            app.SamplingFrequencyField.Visible = 'on';
            app.SamplingFrequencyField.Enable = 'on';
            app.SamplingFrequencyField.Editable = 'on';
            app.SamplingFrequencyLabel.Visible = 'on';
            app.SamplingFrequencyLabel.Enable = 'on';
        end

        function computeInput(app)
            t = 0:1/app.f_s:app.simTime;
            f = str2func(['@(t)' app.FunctionField.Value]);
            app.x_in = f(t);
        end

        function createLists(app)
            app.VoltagesList.Items = cellstr( "V(" + app.order.IDs + ")" );
            app.CurrentsList.Items = cellstr( "I(" + app.order.IDs + ")" );
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath( "Functions" );
            title( app.SignalsPlot , 'Signal Scope' , 'Fontsize' , 16 , 'interpreter' , 'latex' );
            xlabel( app.SignalsPlot , '$t$ [s]' , 'Interpreter' , 'latex' , 'FontSize' , 16 );
            ylabel( app.SignalsPlot , '$v \, (t)$ [V]' , 'Interpreter' , 'latex' , 'FontSize' , 16 );
            title( app.GraphPlot , 'Network Tree-Cotree Decomposition' , 'Fontsize' , 16 , 'interpreter' , 'latex' );
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.progressBar = uiprogressdlg( app.ATTILAUIFigure , 'Title' , 'Simulating' , ...
                                             'Indeterminate' , 'on' , 'Cancelable' , 'on', ...
                                             'Message' , 'Model generation' );
            drawnow;    

            if app.InputTypeDropDown.Value == "Function"
                computeInput( app );
            end

            if app.x_in == 0
                app.errorWindow = errordlg( 'No input! Please, select a valid input.' , 'Input Error');
            end

%             if app.f_s * app.simTime >= length( app.x_in )
%                 XIN = app.x_in;
%                 app.SimulationTimeField.Value = length( app.x_in ) / app.f_s;
%             else
%                 XIN = app.x_in( 1 : app.f_s * app.simTime );
%             end
            
            XIN = app.x_in;
            FS = app.f_s;
            NP = app.netlistPath;
            WT = app.waveType;
            LMSDM = app.lmsdm;
            IS = app.iterSol;
            TDSR = app.tolDSR;
            TSLV = app.tolSLV;
            LEFP = app.toLEFP;
            
            [ nBuff , scat_funcs , S_fun , R_th_funcs , out_fun , app.order , Z , rho , solv_fun ] = buildWDF( NP , 'N002' , FS , LMSDM , WT , [] , IS , app );

            app.progressBar.Message = 'Model simulation';

            [ app.y_out , app.v_out , app.i_out , app.eTime ] = processWDF( XIN , nBuff , app.order , Z , S_fun , scat_funcs , R_th_funcs , out_fun , rho , solv_fun , TSLV , TDSR , LEFP );

            app.time = ( 0 : size( app.v_out , 1 ) - 1 )' / app.f_s;

            enableElapsedTimeField( app , num2str(app.eTime) )
            
            createLists( app );

            app.SignalsPlot.XLimMode = 'auto';
            app.SignalsPlot.YLimMode = 'auto';
            plot( app.SignalsPlot , app.time , app.y_out , 'LineWidth' , 1 );

            close( app.progressBar );
        end

        % Value changed function: WaveTypeDropDown
        function WaveTypeDropDownValueChanged(app, event)
            app.waveType = app.WaveTypeDropDown.Value;
        end

        % Value changed function: DiscretizationMethodDropDown
        function DiscretizationMethodDropDownValueChanged(app, event)
            app.lmsdm = app.DiscretizationMethodDropDown.Value;
        end

        % Value changed function: IterativeSolverDropDown
        function IterativeSolverDropDownValueChanged(app, event)
            app.iterSol = app.IterativeSolverDropDown.Value;
            if app.iterSol == "EFP"
                enableOrderInput( app );
            else 
                disableOrderInput( app );
            end
        end

        % Value changed function: DSRToleranceField
        function DSRToleranceFieldValueChanged(app, event)
            app.tolDSR = app.DSRToleranceField.Value;
        end

        % Value changed function: SolverToleranceField
        function SolverToleranceFieldValueChanged(app, event)
            app.tolSLV = app.SolverToleranceField.Value;
        end

        % Button pushed function: BrowseNetlistButton
        function BrowseNetlistButtonPushed(app, event)
            [ netlist , path ] = uigetfile( { '*.txt' , 'Text file (*.txt)' ; ...
                                              '*.net' , 'Netlist file (*.net)' } , ...
                                              'Select a File' );
            if ~isequal( netlist , 0 )
                app.netlistPath = fullfile( path , netlist );
                app.NetlistFileLabel.Text = netlist;
            end
            disableElapsedTimeField( app )
        end

        % Value changed function: FunctionField, InputTypeDropDown
        function InputtypeDropDownValueChanged(app, event)
            switch app.InputTypeDropDown.Value
                case 'Function'
                    app.BrowseInputLabel.Text = '';
                    app.x_in = 0;
                    enableSamplingFrequencyField( app )
                    disableBrowseInput( app );
                    enableFunctionInput( app );
                    app.SimulationTimeField.Enable = 'on';
                case 'File'
                    app.x_in = 0;
                    disableSamplingFrequencyField( app )
                    disableFunctionInput( app );
                    enableBrowseInput( app );
                    app.SimulationTimeField.Value = 0;
                    app.SimulationTimeField.Enable = 'off';
                    
            end
            disableElapsedTimeField( app )
        end

        % Button pushed function: BrowseInputButton
        function BrowseInputButtonPushed(app, event)
            [ input , path ] = uigetfile( { '*.wav' , 'Wave file (*.wav)' ; ...
                                            '*.txt' , 'Text file (*.txt)' ; ...
                                            '*.mat' , 'MATLAB file (*.mat)' } , ...
                                            'Select a File' );
            if ~isequal( input , 0 )
                app.inputPath = fullfile( path , input );
                app.BrowseInputLabel.Text = input;
                [ ~ , ~ , ext ] = fileparts( input );
                switch ext
                    case '.wav'
                        [ data , fs ] = audioread( app.inputPath );
                        app.x_in = data( : , 1 );
                        app.f_s = fs;
                    case '.txt'
                        data = readmatrix( app.inputPath );
                        app.x_in = data( : , 2 );
                        app.f_s = round( 1 / ( data( 2 , 1 ) - data( 1 , 1 ) ) );
                    case '.mat'
                        data = load( app.inputPath );
                        app.x_in = data.signal( : , 1 );
                        app.f_s = round( 1 / ( data.time( 2 ) - data.time( 1 ) ) );
                end
                app.simTime = length( app.x_in ) / app.f_s;
                app.SimulationTimeField.Value = app.simTime;
                app.SimulationTimeField.Enable = 'off';
            end
        end

        % Value changed function: DSRAlgorithmCheckBox
        function DSRAlgorithmCheckBoxValueChanged(app, event)
            switch app.DSRAlgorithmCheckBox.Value
                case 1
                    app.DSRToleranceField.Enable = 'on';
                    app.DSRtoleranceEditFieldLabel.Enable = 'on';
                    app.tolDSR = app.DSRToleranceField.Value;
                case 0
                    app.DSRToleranceField.Enable = 'off';
                    app.DSRtoleranceEditFieldLabel.Enable = 'off';
                    app.tolDSR = 0;
            end
        end

        % Value changed function: VoltagesList
        function VoltagesListValueChanged(app, event)
            app.CurrentsList.Value = {};
            [ ~ , app.selectedVoltages ] = ismember( app.VoltagesList.Value , app.VoltagesList.Items );
            app.signals = app.v_out( : , app.selectedVoltages );
            app.typeFlag = 'Volts';
        end

        % Value changed function: CurrentsList
        function CurrentsListValueChanged(app, event)
            app.VoltagesList.Value = {};
            [ ~ , app.selectedCurrents ] = ismember( app.CurrentsList.Value , app.CurrentsList.Items );
            app.signals = app.i_out( : , app.selectedCurrents );
            app.typeFlag = 'Amperes';
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            cla( app.SignalsPlot );
            app.SignalsPlot.XLimMode = 'auto';
            app.SignalsPlot.YLimMode = 'auto';
            plot( app.SignalsPlot , app.time , app.signals , 'LineWidth' , 1 );
            switch app.typeFlag
                case 'Volts'
                    ylabel( app.SignalsPlot , '$v \, (t)$ [V]' , 'Interpreter' , 'latex' , 'FontSize' , 12 );
                case 'Amperes'
                    ylabel( app.SignalsPlot , '$i \, (t)$ [A]' , 'Interpreter' , 'latex' , 'FontSize' , 12 );
            end
        end

        % Value changed function: SimulationTimeField
        function SimulationTimeFieldValueChanged(app, event)
            app.simTime = app.SimulationTimeField.Value;
        end

        % Value changed function: OrderField
        function OrderFieldValueChanged(app, event)
            app.toLEFP = app.OrderField.Value;
        end

        % Value changing function: FunctionField
        function FunctionFieldValueChanging(app, event)

        end

        % Value changed function: SamplingFrequencyField
        function SamplingFrequencyFieldValueChanged(app, event)
            app.f_s = app.SamplingFrequencyField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ATTILAUIFigure = uifigure('Visible', 'off');
            app.ATTILAUIFigure.Name = 'ATTILA';
            app.AppGridLayout = uigridlayout(app.ATTILAUIFigure);
            app.TabGroup = uitabgroup(app.AppGridLayout);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GeneralGridLayout = uigridlayout(app.GeneralTab);
            app.WavetypeDropDownLabel = uilabel(app.GeneralGridLayout);
            app.WavetypeDropDownLabel.Text = 'Wave type:';
            app.WaveTypeDropDown = uidropdown(app.GeneralGridLayout);
            app.WaveTypeDropDown.Items = {'Voltage', 'Current', 'Power-Normalized'};
            app.WaveTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveTypeDropDownValueChanged, true);
            app.WaveTypeDropDown.Value = 'Voltage';
            app.DiscretizationmethodDropDownLabel = uilabel(app.GeneralGridLayout);
            app.DiscretizationmethodDropDownLabel.Text = 'Discretization method:';
            app.DiscretizationMethodDropDown = uidropdown(app.GeneralGridLayout);
            app.DiscretizationMethodDropDown.Items = {'Backward Euler', 'Trapezoidal', 'BDF2', 'BDF3', 'BDF4', 'AM2', 'AM3'};
            app.DiscretizationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DiscretizationMethodDropDownValueChanged, true);
            app.DiscretizationMethodDropDown.Value = 'Trapezoidal';
            app.IterativesolverDropDownLabel = uilabel(app.GeneralGridLayout);
            app.IterativesolverDropDownLabel.Text = 'Iterative solver:';
            app.IterativeSolverDropDown = uidropdown(app.GeneralGridLayout);
            app.IterativeSolverDropDown.Items = {'SIM', 'WDNR', 'WDFNR', 'EFP', 'WDPP'};
            app.IterativeSolverDropDown.ValueChangedFcn = createCallbackFcn(app, @IterativeSolverDropDownValueChanged, true);
            app.IterativeSolverDropDown.Value = 'SIM';
            app.InputtypeDropDownLabel = uilabel(app.GeneralGridLayout);
            app.InputtypeDropDownLabel.Text = 'Input type:';
            app.InputTypeDropDown = uidropdown(app.GeneralGridLayout);
            app.InputTypeDropDown.Items = {'Function', 'File'};
            app.InputTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InputtypeDropDownValueChanged, true);
            app.InputTypeDropDown.Value = 'Function';
            app.DSRAlgorithmCheckBox = uicheckbox(app.GeneralGridLayout);
            app.DSRAlgorithmCheckBox.ValueChangedFcn = createCallbackFcn(app, @DSRAlgorithmCheckBoxValueChanged, true);
            app.DSRAlgorithmCheckBox.Text = '';
            app.DSRAlgorithmCheckBox.Value = true;
            app.DSRAlgorithmLabel = uilabel(app.GeneralGridLayout);
            app.DSRAlgorithmLabel.Text = 'DSR algorithm:';
            app.BrowseNetlistButton = uibutton(app.GeneralGridLayout, 'push');
            app.BrowseNetlistButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseNetlistButtonPushed, true);
            app.BrowseNetlistButton.Text = 'Browse ';
            app.SPICENetlistLabel = uilabel(app.GeneralGridLayout);
            app.SPICENetlistLabel.Text = 'SPICE netlist:';
            app.InputSignalLabel = uilabel(app.GeneralGridLayout);
            app.InputSignalLabel.Text = 'Input signal';
            app.CircuitNetlistLabel = uilabel(app.GeneralGridLayout);
            app.CircuitNetlistLabel.Text = 'Circuit netlist';
            app.SimulationOptionsLabel = uilabel(app.GeneralGridLayout);
            app.SimulationOptionsLabel.Text = 'Simulation options';
            app.SolverSettingsLabel = uilabel(app.GeneralGridLayout);
            app.SolverSettingsLabel.Text = 'Iterative solver settings';
            app.FunctionField = uieditfield(app.GeneralGridLayout, 'text');
            app.FunctionField.ValueChangedFcn = createCallbackFcn(app, @InputtypeDropDownValueChanged, true);
            app.FunctionField.ValueChangingFcn = createCallbackFcn(app, @FunctionFieldValueChanging, true);
            app.SimulateButton = uibutton(app.GeneralGridLayout, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SolvertoleranceVEditFieldLabel = uilabel(app.GeneralGridLayout);
            app.SolvertoleranceVEditFieldLabel.Text = 'Solver tolerance [V]:';
            app.SolverToleranceField = uieditfield(app.GeneralGridLayout, 'numeric');
            app.SolverToleranceField.Limits = [1e-15 Inf];
            app.SolverToleranceField.ValueChangedFcn = createCallbackFcn(app, @SolverToleranceFieldValueChanged, true);
            app.SolverToleranceField.Value = 0.001;
            app.DSRtoleranceEditFieldLabel = uilabel(app.GeneralGridLayout);
            app.DSRtoleranceEditFieldLabel.Text = 'DSR tolerance [Ω]:';
            app.DSRToleranceField = uieditfield(app.GeneralGridLayout, 'numeric');
            app.DSRToleranceField.Limits = [0 Inf];
            app.DSRToleranceField.ValueChangedFcn = createCallbackFcn(app, @DSRToleranceFieldValueChanged, true);
            app.DSRToleranceField.Value = 1000;
            app.NetlistFileLabel = uilabel(app.GeneralGridLayout);
            app.NetlistFileLabel.Text = '';
            app.BrowseInputButton = uibutton(app.GeneralGridLayout, 'push');
            app.BrowseInputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputButtonPushed, true);
            app.BrowseInputButton.Enable = 'off';
            app.BrowseInputButton.Visible = 'off';
            app.BrowseInputButton.Text = 'Browse';
            app.SimulationTimeField = uieditfield(app.GeneralGridLayout, 'numeric');
            app.SimulationTimeField.Limits = [0 Inf];
            app.SimulationTimeField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimeFieldValueChanged, true);
            app.SimulationTimeField.Value = 0.01;
            app.BrowseInputLabel = uilabel(app.GeneralGridLayout);
            app.BrowseInputLabel.Enable = 'off';
            app.BrowseInputLabel.Visible = 'off';
            app.BrowseInputLabel.Text = '';
            app.SimulationlengthsLabel = uilabel(app.GeneralGridLayout);
            app.SimulationlengthsLabel.Text = 'Simulation length [s]:';
            app.OrderLabel = uilabel(app.GeneralGridLayout);
            app.OrderLabel.Enable = 'off';
            app.OrderLabel.Visible = 'off';
            app.OrderLabel.Text = 'Order:';
            app.OrderField = uieditfield(app.GeneralGridLayout, 'numeric');
            app.OrderField.Limits = [1e-15 Inf];
            app.OrderField.ValueChangedFcn = createCallbackFcn(app, @OrderFieldValueChanged, true);
            app.OrderField.Editable = 'off';
            app.OrderField.Enable = 'off';
            app.OrderField.Visible = 'off';
            app.OrderField.Value = 1;
            app.ElapsedTimeLabel = uilabel(app.GeneralGridLayout);
            app.ElapsedTimeLabel.Enable = 'off';
            app.ElapsedTimeLabel.Visible = 'off';
            app.ElapsedTimeLabel.Text = 'Elapsed Time [s]:';
            app.ElapsedTimeValue = uilabel(app.GeneralGridLayout);
            app.ElapsedTimeValue.Enable = 'off';
            app.ElapsedTimeValue.Visible = 'off';
            app.ElapsedTimeValue.Text = '0';
            app.SamplingFrequencyLabel = uilabel(app.GeneralGridLayout);
            app.SamplingFrequencyLabel.Text = 'Sampling frequency [Hz]:';
            app.SamplingFrequencyField = uieditfield(app.GeneralGridLayout, 'numeric');
            app.SamplingFrequencyField.ValueDisplayFormat = '%.0f';
            app.SamplingFrequencyField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyFieldValueChanged, true);
            app.SamplingFrequencyField.Value = 48000;
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Tooltip = {''};
            app.ModelTab.Title = 'Model';
            app.ModelGridLayout = uigridlayout(app.ModelTab);
            app.CurrentGraphPlot = uiaxes(app.ModelGridLayout);
            title(app.CurrentGraphPlot, 'I-Network Tree-Cotree Decomposition')
            app.CurrentGraphPlot.XTick = [];
            app.CurrentGraphPlot.YTick = [];
            app.CurrentGraphPlot.ZTick = [];
            app.CurrentGraphPlot.Visible = 'off';
            app.VoltageGraphPlot = uiaxes(app.ModelGridLayout);
            title(app.VoltageGraphPlot, 'V-Network Tree-Cotree Decomposition')
            app.VoltageGraphPlot.XTick = [];
            app.VoltageGraphPlot.YTick = [];
            app.VoltageGraphPlot.ZTick = [];
            app.VoltageGraphPlot.Visible = 'off';
            app.GraphPlot = uiaxes(app.ModelGridLayout);
            title(app.GraphPlot, 'Network Tree-Cotree Decomposition ')
            app.GraphPlot.XTick = [];
            app.GraphPlot.YTick = [];
            app.GraphPlot.ZTick = [];
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.OutputGridLayout = uigridlayout(app.OutputTab);
            app.SignalsPlot = uiaxes(app.OutputGridLayout);
            title(app.SignalsPlot, 'Signal Scope')
            xlabel(app.SignalsPlot, 't [s]')
            ylabel(app.SignalsPlot, 'v(t) [V]')
            zlabel(app.SignalsPlot, 'Z')
            app.CurrentsList = uilistbox(app.OutputGridLayout);
            app.CurrentsList.Items = {};
            app.CurrentsList.Multiselect = 'on';
            app.CurrentsList.ValueChangedFcn = createCallbackFcn(app, @CurrentsListValueChanged, true);
            app.CurrentsList.Value = {};
            app.VoltagesList = uilistbox(app.OutputGridLayout);
            app.VoltagesList.Items = {};
            app.VoltagesList.Multiselect = 'on';
            app.VoltagesList.ValueChangedFcn = createCallbackFcn(app, @VoltagesListValueChanged, true);
            app.VoltagesList.Value = {};
            app.ComputeButton = uibutton(app.OutputGridLayout, 'push');
            app.ComputeButton.Text = 'Compute';
            app.DisplayButton = uibutton(app.OutputGridLayout, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.SumField = uieditfield(app.OutputGridLayout, 'text');
            app.Image = uiimage(app.AppGridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Assets', 'logo2.png');
            % Show the figure after all components are created
            app.ATTILAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ATTILAUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ATTILAUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ATTILAUIFigure)
        end
    end
end